classdef xromm_visualisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CoregistrationfileTXTEditField  matlab.ui.control.EditField
        CoregistrationfileTXTEditFieldLabel  matlab.ui.control.Label
        CoregFileButton                 matlab.ui.control.Button
        MocapFileC3DEditField           matlab.ui.control.EditField
        MocapFileC3DEditFieldLabel      matlab.ui.control.Label
        MocapFileButton                 matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        TrackingFilesPanel              matlab.ui.container.Panel
        TrackingDirectoryField          matlab.ui.control.EditField
        CurrentDirectoryLabel_2         matlab.ui.control.Label
        TrackingListListBox             matlab.ui.control.ListBox
        TrackingListListBoxLabel        matlab.ui.control.Label
        TrackAddButton                  matlab.ui.control.StateButton
        TrackDeleteButton               matlab.ui.control.StateButton
        TrackDownButton                 matlab.ui.control.StateButton
        TrackUpButton                   matlab.ui.control.StateButton
        BonesPanel                      matlab.ui.container.Panel
        BonesDirectoryField             matlab.ui.control.EditField
        CurrentDirectoryLabel           matlab.ui.control.Label
        BoneListListBox                 matlab.ui.control.ListBox
        BoneListListBoxLabel            matlab.ui.control.Label
        BoneAddButton                   matlab.ui.control.StateButton
        BoneDeleteButton                matlab.ui.control.StateButton
        BoneDownButton                  matlab.ui.control.StateButton
        BoneUpButton                    matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        PlayButton                      matlab.ui.control.StateButton
        FrameSlider                     matlab.ui.control.Slider
        FrameLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Menu                            matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        CleartrialdataonlyMenu          matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        biplane; % structure for the processed bone and tracking biplane data
        bone_files; % list of current bone files
        tracking_files; % list of current tracking files
        mocap; %data loaded from c3d file
        patch_store; %structure to store bone and marker patches
    end
    
    methods (Access = private)
        
        function biplane = xromm_space_process(app)
            Bones = app.bone_files.files;
            
            % loop through each bone and load the vrml file into a 3d model
            % or patch, then for each frame, rotate the patch according to
            % the transformation matrix in the tracking files
            for b = 1:length(Bones)
                [~,bonename,~] = fileparts(app.bone_files.files{b});

                [biplane.vertices.CT_fullpatch.(bonename), biplane.faces.CT_fullpatch.(bonename)] = read_vrml_fast([app.bone_files.directory app.bone_files.files{b}]);
                %Faces for matlab
                biplane.faces.CT_fullpatch.(bonename)(:,4) = [];
                biplane.faces.CT_fullpatch.(bonename) = biplane.faces.CT_fullpatch.(bonename)+1;
                % convert vertices from mm to m
                biplane.vertices.CT_fullpatch.(bonename) = biplane.vertices.CT_fullpatch.(bonename)/1000;

                % reduce number of faces and vertices to plot quicker
                [biplane.faces.CT.(bonename), biplane.vertices.CT.(bonename)] =reducepatch(biplane.faces.CT_fullpatch.(bonename)(:,1:3), biplane.vertices.CT_fullpatch.(bonename),0.1);
                
                % load the tracking data
                biplane.raw_xray.(bonename) = load_tra_file([app.tracking_files.directory app.tracking_files.files{b}]);
                
                % loop through all frames and transform the bone 3d model
                for F = 1 : size(biplane.raw_xray.(bonename),3)
                    BTranformation_GL.(bonename)(:,:,F) = biplane.raw_xray.(bonename)(:,:,F);
                    % Transform Vertices to Local Coordinate System
                    biplane.vertices.MotionL.(bonename)(:,:,F) = transformVertices_GL(biplane.vertices.CT.(bonename),BTranformation_GL.(bonename)(:,:,F));
                end
                
                if b == 1
                    max_x = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))));
                    min_x = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))));
                    max_y = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))));
                    min_y = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))));
                    max_z = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))));
                    min_z = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))));
                else 
                    max_x = max([max_x max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))))]);
                    min_x = min([min_x min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))))]);
                    max_y = max([max_y max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))))]);
                    min_y = min([min_y min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))))]);
                    max_z = max([max_z max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))))]);
                    min_z = min([min_z min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))))]);
                end
                
            end
            
            biplane.boundaries.max_x = max_x;
            biplane.boundaries.min_x = min_x;
            biplane.boundaries.max_y = max_y;
            biplane.boundaries.min_y = min_y;
            biplane.boundaries.max_z = max_z;
            biplane.boundaries.min_z = min_z;


            biplane.no_frames = size(biplane.raw_xray.(bonename),3);
            biplane.drawfirst = false;

        end
        
        function biplane = mocap_space_process(app)
            
            Bones = app.bone_files.files;
            
            
            for b = 1:length(app.bone_files.files)
                [~,bonename,~] = fileparts(app.bone_files.files{b});
                % load tracking data
                biplane.raw_xray.(bonename) = load_tra_file([app.tracking_files.directory app.tracking_files.files{b}]);
                
                % Load transformation matrix from Xray to Optical Space
                TransformMatrix = load(app.CoregistrationfileTXTEditField.Value,'/');
                TransformMatrix_Opt_to_Xray = TransformMatrix';
                % Covert positions to mm to m
                TransformMatrix_Opt_to_Xray(1:3,4) = TransformMatrix_Opt_to_Xray(1:3,4)/1000;
                
                % Tranform biplane to optical motion capture system
                for F = 1 : size(biplane.raw_xray.(bonename),3)
                    biplane.raw_mocap.(bonename)(:,:,F)=TransformMatrix_Opt_to_Xray*biplane.raw_xray.(bonename)(:,:,F);
                end
                
                % load the bone data
                [biplane.vertices.CT_fullpatch.(bonename), biplane.faces.CT_fullpatch.(bonename)] = read_vrml_fast([app.bone_files.directory app.bone_files.files{b}]);
                %Faces for matlab
                biplane.faces.CT_fullpatch.(bonename)(:,4) = [];
                biplane.faces.CT_fullpatch.(bonename) = biplane.faces.CT_fullpatch.(bonename)+1;
                % convert vertices from mm to m
                biplane.vertices.CT_fullpatch.(bonename) = biplane.vertices.CT_fullpatch.(bonename)/1000;

                % reduce number of faces and vertices to plot quicker
                [biplane.faces.CT.(bonename), biplane.vertices.CT.(bonename)] =reducepatch(biplane.faces.CT_fullpatch.(bonename)(:,1:3), biplane.vertices.CT_fullpatch.(bonename),0.1);

                for F = 1 : size(biplane.raw_mocap.(bonename),3)
                    BTranformation_GL.(bonename)(:,:,F) = biplane.raw_mocap.(bonename)(:,:,F);
                    % Transform Vertices and AxesSystem to Local Coordinate System
                    biplane.vertices.MotionL.(bonename)(:,:,F) = transformVertices_GL(biplane.vertices.CT.(bonename),BTranformation_GL.(bonename)(:,:,F));
                end
                
                if b == 1
                    max_x = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))));
                    min_x = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))));
                    max_y = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))));
                    min_y = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))));
                    max_z = max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))));
                    min_z = min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))));
                else 
                    max_x = max([max_x max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))))]);
                    min_x = min([min_x min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,1,:))))]);
                    max_y = max([max_y max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))))]);
                    min_y = min([min_y min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,2,:))))]);
                    max_z = max([max_z max(max(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))))]);
                    min_z = min([min_z min(min(squeeze(biplane.vertices.MotionL.(bonename)(:,3,:))))]);
                end
                                
            end
            
            biplane.boundaries.max_x = max_x;
            biplane.boundaries.min_x = min_x;
            biplane.boundaries.max_y = max_y;
            biplane.boundaries.min_y = min_y;
            biplane.boundaries.max_z = max_z;
            biplane.boundaries.min_z = min_z;
            
            biplane.no_frames = size(biplane.raw_xray.(bonename),3);
            biplane.drawfirst = false;

        end
        
        % function to draw the bones in the figure
        function draw_bones(app)
            
            %get current view angle
            view_angle = app.UIAxes.View;
            %get current frame
            F = app.FrameSlider.Value;
            %get list of bones and go through one by one and draw
            Bones = app.bone_files.files;
            
            hold(app.UIAxes, 'on' ); 
            for b = 1:length(app.bone_files.files)
                %current bone 
                [~,bonename,~] = fileparts(app.bone_files.files{b}); 
                %plot surface
                if ~isfield(app.patch_store,bonename)
                    app.patch_store.(bonename) = trisurf(app.biplane.faces.CT.(bonename),(app.biplane.vertices.MotionL.(bonename)(:,1,F)),(app.biplane.vertices.MotionL.(bonename)(:,2,F)),(app.biplane.vertices.MotionL.(bonename)(:,3,F)),'FaceColor',[0.98 0.98 0.98],'FaceAlpha',.9,'EdgeColor','none','FaceLighting','gouraud','Parent', app.UIAxes);
                    hold(app.UIAxes, 'on' );
                else
                    app.patch_store.(bonename).Vertices = squeeze(app.biplane.vertices.MotionL.(bonename)(:,:,F));
                end
            end
            hold(app.UIAxes, 'off' );
            lighting(app.UIAxes, 'gouraud')
            
            b_offset = 0.1;
             %axis(app.UIAxes,'equal');
            if app.biplane.drawfirst
                app.UIAxes.View = view_angle;
                 %restrict the size of the axes and make equal
                axis(app.UIAxes, 'equal',[app.biplane.boundaries.min_x-b_offset app.biplane.boundaries.max_x+b_offset app.biplane.boundaries.min_y-b_offset app.biplane.boundaries.max_y+b_offset app.biplane.boundaries.min_z-b_offset app.biplane.boundaries.max_z+b_offset])
            else 
                app.biplane.drawfirst = true;
                axis(app.UIAxes, 'equal')                
                axis(app.UIAxes,[app.biplane.boundaries.min_x-b_offset app.biplane.boundaries.max_x+b_offset app.biplane.boundaries.min_y-b_offset app.biplane.boundaries.max_y+b_offset app.biplane.boundaries.min_z-b_offset app.biplane.boundaries.max_z+b_offset])
                app.UIAxes.View = [-25 30];
            end
                       
            % put some light at different angles based on the view angle
            if ~isfield(app.patch_store,'L1')
                app.patch_store.L1 = camlight(app.UIAxes,0,0);
                app.patch_store.L2 = camlight(app.UIAxes,90,90);
                app.patch_store.L3 = camlight(app.UIAxes,180,180);
            else 
%                 camlight(app.patch_store.L1,'headlight');
%                 camlight(app.patch_store.L2,'left');
%                 camlight(app.patch_store.L3,'right');
            end
%             camlight(app.UIAxes, view_angle(1)-65,view_angle(2))
%             camlight(app.UIAxes, view_angle(1),view_angle(2))
%             camlight(app.UIAxes, view_angle(1)+90,view_angle(2))
            
        end
        
        % function to draw small spheres for each frame of data
        function draw_markers(app)
            % add constant frame offset
            f_offset = 1;
            %get current frame
            F = app.FrameSlider.Value+app.mocap.trigger_frame + f_offset;
            %get list of markers
            markerlist = fieldnames(app.mocap.marker_data.Markers);
            
            % create sphere with radius of 0.01m
            radius = 0.002;
            [x,y,z] = sphere(5);
            x = x*radius; y = y*radius; z = z*radius;
            
            % loop through the markers and plot the marker - add the
            % markers coordinates (X,Y,Z) to the sphere and make patch to
            % show in the figure.
            for i = 1:length(markerlist)
                % get marker position - use interpolation to account for
                % differences in the frame acquisition rate (xray capture
                % rate / clock was 99% that of mocap)
                marker_pos = interp1(app.mocap.marker_data.Markers.(markerlist{i}),F*(1/0.99));
                if strcmp(app.mocap.marker_data.Info.units.ALLMARKERS,'mm')
                    marker_pos = marker_pos/1000;
                end                             
                
                % offset the sphere based on coordinates
                xi = x + marker_pos(1);
                yi = y + marker_pos(2);
                zi = z + marker_pos(3);
                % make patch
                marker_patch = surf2patch(xi,yi,zi);               
                
                marker_name = ['marker_' num2str(i)];
                if ~isfield(app.patch_store,marker_name)             
                    app.patch_store.(marker_name) = patch(app.UIAxes,marker_patch,'FaceColor','c','LineStyle','none');
                    hold(app.UIAxes, 'on' ); 
                else
                    app.patch_store.(marker_name).Vertices = marker_patch.vertices;
                end
            end
            hold(app.UIAxes, 'off' );            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BoneAddButton
        function BoneAddButtonValueChanged(app, event)
            % if button is pushed then add file/s to list
            
            % select file using uigetfile function 
            [files, pathname, filterindex] = uigetfile('*.iv','Select bone file (*.iv)',"MultiSelect",'on');
            % if cancelled then leave function
            if ~filterindex
                return
            end
            % if a single file is selected this will be a string - convert
            % this to cell
            if ischar(files)
                files = {files};
            end
            
            % if directory is empty (i.e first run) then make the new
            % directory the one selected and make the list of files the
            % file/s selected
            if ~isfield(app.bone_files,'directory')
                app.bone_files.directory = pathname;
                app.bone_files.files = files';                  
            else 
                % if the current directory is different from previous then
                % clear the bone list and start again
                if ~strcmp(app.bone_files.directory,pathname)
                    app.BoneListListBox.Value = {''};
                    app.bone_files.directory = pathname;
                    app.bone_files.files = files;
                else % if the directory is the same, then just add new bones to end of the list
                    app.bone_files.files = [app.bone_files.files; files'];
                end
            end
            
            % update the list and directory value
            app.BonesDirectoryField.Value = app.bone_files.directory;
            app.BoneListListBox.Items = app.bone_files.files;    
            
            % change button back to off
            app.BoneAddButton.Value = 0;
            
        end

        % Value changed function: BoneDeleteButton
        function BoneDeleteButtonValueChanged(app, event)
                        
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.bone_files,'directory')
                return
            end
            
            %delete the value of the list from the files list property
            app.bone_files.files(strcmp(app.bone_files.files,app.BoneListListBox.Value)) = [];
            %update the list 
            app.BoneListListBox.Items = app.bone_files.files;  
            
            app.BoneDeleteButton.Value = 0;
        end

        % Value changed function: BoneUpButton
        function BoneUpButtonValueChanged(app, event)
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.bone_files,'directory')
                return
            end
            
            %find the value selected and the value before and store these
            current_num = find(strcmp(app.bone_files.files,app.BoneListListBox.Value));
            if current_num == 1
                return
            end
            current_val = app.BoneListListBox.Items(current_num);
            new_num = current_num - 1;
            new_val = app.BoneListListBox.Items(new_num);
            
            % replace the values based on the indices
            app.bone_files.files(new_num) = current_val;
            app.bone_files.files(current_num) = new_val;
            
            %update the list 
            app.BoneListListBox.Items = app.bone_files.files; 
            
            
            app.BoneUpButton.Value = 0;
            
        end

        % Value changed function: BoneDownButton
        function BoneDownButtonValueChanged(app, event)
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.bone_files,'directory')
                return
            end
            
            %find the value selected and the value before and store these
            current_num = find(strcmp(app.bone_files.files,app.BoneListListBox.Value));
            if current_num == length(app.bone_files.files)
                return
            end
            current_val = app.BoneListListBox.Items(current_num);
            new_num = current_num + 1;
            new_val = app.BoneListListBox.Items(new_num);
            
            % replace the values based on the indices
            app.bone_files.files(new_num) = current_val;
            app.bone_files.files(current_num) = new_val;
            
            %update the list 
            app.BoneListListBox.Items = app.bone_files.files; 
            
            app.BoneDownButton.Value = 0;
            
        end

        % Value changed function: TrackAddButton
        function TrackAddButtonValueChanged(app, event)
            % if button is pushed then add file/s to list - don't add if
            % the bone files aren't selected
            if ~isfield(app.bone_files,'files')
                errordlg('Please load bones before tracking data.', 'Bone eror');
                return
            end
            
            % select file using uigetfile function 
            [files, pathname, filterindex] = uigetfile('*.tra','Select tracking (TRA) file',"MultiSelect",'on');
            % if cancelled then leave function
            if ~filterindex
                return
            end
            % if a single file is selected this will be a string - convert
            % this to cell
            if ischar(files)
                files = {files};
            end
            
            % if directory is empty (i.e first run) then make the new
            % directory the one selected and make the list of files the
            % file/s selected
            if ~isfield(app.tracking_files,'directory')
                app.tracking_files.directory = pathname;
                app.tracking_files.files = files';                  
            else 
                % if the current directory is different from previous then
                % clear the tracking list and start again
                if ~strcmp(app.tracking_files.directory,pathname)
                    app.TrackingListListBox.Value = {''};
                    app.tracking_files.directory = pathname;
                    app.tracking_files.files = files;
                else % if the directory is the same, then just add new tracking files to end of the list
                    app.tracking_files.files = [app.tracking_files.files; files];
                end
            end
            
            % update the list and directory value
            app.TrackingDirectoryField.Value = app.tracking_files.directory;
            app.TrackingListListBox.Items = app.tracking_files.files;    
            
            % change button back to off
            app.TrackAddButton.Value = 0;
            
        end

        % Value changed function: TrackDeleteButton
        function TrackDeleteButtonValueChanged(app, event)
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.tracking_files,'directory')
                return
            end
            
            %delete the value of the list from the files list property
            app.tracking_files.files(strcmp(app.tracking_files.files,app.TrackingListListBox.Value)) = [];
            %update the list 
            app.TrackingListListBox.Items = app.tracking_files.files;  
            
            app.TrackDeleteButton.Value = 0;       
        end

        % Value changed function: TrackUpButton
        function TrackUpButtonValueChanged(app, event)
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.tracking_files,'directory')
                return
            end
            
            %find the value selected and the value before and store these
            current_num = find(strcmp(app.tracking_files.files,app.TrackingListListBox.Value));
            if current_num == 1
                return
            end
            current_val = app.TrackingListListBox.Items(current_num);
            new_num = current_num - 1;
            new_val = app.TrackingListListBox.Items(new_num);
            
            % replace the values based on the indices
            app.tracking_files.files(new_num) = current_val;
            app.tracking_files.files(current_num) = new_val;
            
            %update the list 
            app.TrackingListListBox.Items = app.tracking_files.files; 
            
            
            app.TrackUpButton.Value = 0;
        end

        % Value changed function: TrackDownButton
        function TrackDownButtonValueChanged(app, event)
            %if a current directory doesn't exist then the list hasn't been
            %created yet
            if ~isfield(app.tracking_files,'directory')
                return
            end
            
            %find the value selected and the value before and store these
            current_num = find(strcmp(app.tracking_files.files,app.TrackingListListBox.Value));
            if current_num == length(app.tracking_files.files)
                return
            end
            current_val = app.TrackingListListBox.Items(current_num);
            new_num = current_num + 1;
            new_val = app.TrackingListListBox.Items(new_num);
            
            % replace the values based on the indices
            app.tracking_files.files(new_num) = current_val;
            app.tracking_files.files(current_num) = new_val;
            
            %update the list 
            app.TrackingListListBox.Items = app.tracking_files.files; 
            
            app.TrackDownButton.Value = 0; 
        end

        % Callback function: FrameSlider, FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            if isfield (app.biplane,'no_frames')
                draw_bones(app)
            end
            
            if ~isempty(app.MocapFileC3DEditField.Value)
                draw_markers(app)
            end
            drawnow;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % make sure there is the same number of bones as tracking files
            if length(app.bone_files.files) ~= length(app.tracking_files.files)
                errordlg('Please load bones before tracking data.', 'Bone eror');
                return
            end
            
            if ~isempty(app.CoregistrationfileTXTEditField.Value)
                app.biplane = mocap_space_process(app);
            else 
                app.biplane = xromm_space_process(app);
            end
            
            
            if ~isempty(app.patch_store)
                % clear any current patches (3d objects)
                fnames = fieldnames(app.patch_store);
                for i = 1:length(fnames)
                    app.patch_store = rmfield(app.patch_store,fnames{i});
                end
            end
            cla(app.UIAxes);
            
            % setup the slider limits based on number of biplane frames
            app.FrameSlider.Limits = [1 app.biplane.no_frames];
            app.FrameSlider.Value = 1;
            draw_bones(app)
            
            %load the mocap data into a structure (c3d or mat files)
            if ~isempty(app.MocapFileC3DEditField.Value)
                [~,~,ext] = fileparts(app.MocapFileC3DEditField.Value);
                if strcmp(ext,'.c3d')
                    app.mocap = btk_loadc3d(app.MocapFileC3DEditField.Value);
                    app.mocap.trigger_frame = 126;
                else 
                    a = load(app.MocapFileC3DEditField.Value);
                    fname = fieldnames(a);
                    for i = 1:a.(fname{1}).Trajectories.Labeled.Count
                        app.mocap.marker_data.Markers.(a.(fname{1}).Trajectories.Labeled.Labels{i}) = (squeeze(a.(fname{1}).Trajectories.Labeled.Data(i,1:3,:)))';
                        app.mocap.trigger_frame = round(a.(fname{1}).Events.Frame);
                        app.mocap.marker_data.Info.units.ALLMARKERS='mm';
                    end
                    
                end
                draw_markers(app)
            end
            

        end

        % Button pushed function: MocapFileButton
        function MocapFileButtonPushed(app, event)
            % select file using uigetfile function 
            [file, pathname, filterindex] = uigetfile({'*.c3d;*.mat', 'Select mocap files (*.c3d, *.mat)'; ...
        '*.*',                   'All Files (*.*)'}, ...
        'Pick a file');
            % if cancelled then leave function
            if ~filterindex
                app.MocapFileC3DEditField.Value = [''];
                return
            end
            app.MocapFileC3DEditField.Value = [pathname file];
        end

        % Button pushed function: CoregFileButton
        function CoregFileButtonPushed(app, event)
            % select file using uigetfile function 
            [file, pathname, filterindex] = uigetfile('*.txt','Select co-registration (TXT) file',"MultiSelect",'off');
            % if cancelled then leave function
            if ~filterindex
                app.CoregistrationfileTXTEditField.Value = [''];
                return
            end
            app.CoregistrationfileTXTEditField.Value = [pathname file];
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            if app.PlayButton.Value
                while app.PlayButton.Value
                    app.PlayButton.Text = 'Pause';
                    F = round(app.FrameSlider.Value);
                    if F<app.FrameSlider.Limits(2)
                        app.FrameSlider.Value = F + 1;
                        draw_bones(app)
                        if ~isempty(app.MocapFileC3DEditField.Value)
                            draw_markers(app)
                        end
                        drawnow
                    else
                        app.PlayButton.Value = 0;
                        app.PlayButton.Text = 'Play';
                    end
                end
            else
                app.PlayButton.Text = 'Play';
            end
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            
            app.BoneListListBox.Items = {'Bone File Name'};
            app.BonesDirectoryField.Value = '';
            app.bone_files = [];
            
            app.TrackingListListBox.Items = {'Tracking File Name'};
            app.TrackingDirectoryField.Value = '';
            app.tracking_files = [];
            
            app.MocapFileC3DEditField.Value = '';
            app.CoregistrationfileTXTEditField.Value = '';
            
            app.biplane = [];
            app.mocap = [];
            
            cla(app.UIAxes);
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            biplane_settings.BoneList = app.BoneListListBox.Items;
            biplane_settings.BoneDirectory = app.BonesDirectoryField.Value;
            biplane_settings.BoneFiles = app.bone_files;
            
            biplane_settings.TrackList = app.TrackingListListBox.Items;
            biplane_settings.TrackDirectory = app.TrackingDirectoryField.Value;
            biplane_settings.TrackFiles = app.tracking_files;
            
            biplane_settings.MocapC3D = app.MocapFileC3DEditField.Value;
            biplane_settings.CoregTXT = app.CoregistrationfileTXTEditField.Value;
            
            biplane_data = app.biplane;
            mocap_data = app.mocap;

            [filename, pathname, filterindex] = uiputfile('*.mat', 'Save settings as');
            
            if ~filterindex
                return
            end
            
            save([pathname filename], "mocap_data","biplane_data","biplane_settings");
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [filename, pathname, filterindex] = uigetfile('*.mat', 'Load settings MAT file');
            if ~filterindex
                return
            end
            load([pathname filename],"-mat", "mocap_data","biplane_data","biplane_settings");
            
            app.BoneListListBox.Items = biplane_settings.BoneList;
            app.BonesDirectoryField.Value = biplane_settings.BoneDirectory;
            app.bone_files = biplane_settings.BoneFiles;
            
            app.TrackingListListBox.Items = biplane_settings.TrackList;
            app.TrackingDirectoryField.Value = biplane_settings.TrackDirectory;
            app.tracking_files = biplane_settings.TrackFiles;
            
            app.MocapFileC3DEditField.Value = biplane_settings.MocapC3D;
            app.CoregistrationfileTXTEditField.Value = biplane_settings.CoregTXT;
            
            app.biplane = biplane_data;
            app.mocap = mocap_data;
            
            app.FrameSlider.Value = 1;
            app.FrameSlider.Limits = [1 app.biplane.no_frames];
            app.biplane.drawfirst = false;
            
            draw_bones(app);
            drawnow
        end

        % Menu selected function: CleartrialdataonlyMenu
        function CleartrialdataonlyMenuSelected(app, event)
            
            app.TrackingListListBox.Items = {'Tracking File Name'};
            app.TrackingDirectoryField.Value = '';
            app.tracking_files = [];
            
            app.MocapFileC3DEditField.Value = '';
            
            app.biplane = [];
            app.mocap = [];
            
            cla(app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {680, 680};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {326, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BonesPanel = uipanel(app.LeftPanel);
            app.BonesPanel.Title = 'Bones';
            app.BoneUpButton = uibutton(app.BonesPanel, 'state');
            app.BoneUpButton.ValueChangedFcn = createCallbackFcn(app, @BoneUpButtonValueChanged, true);
            app.BoneUpButton.Text = 'Up';
            app.BoneDownButton = uibutton(app.BonesPanel, 'state');
            app.BoneDownButton.ValueChangedFcn = createCallbackFcn(app, @BoneDownButtonValueChanged, true);
            app.BoneDownButton.Text = 'Down';
            app.BoneDeleteButton = uibutton(app.BonesPanel, 'state');
            app.BoneDeleteButton.ValueChangedFcn = createCallbackFcn(app, @BoneDeleteButtonValueChanged, true);
            app.BoneDeleteButton.Text = 'Delete';
            app.BoneAddButton = uibutton(app.BonesPanel, 'state');
            app.BoneAddButton.ValueChangedFcn = createCallbackFcn(app, @BoneAddButtonValueChanged, true);
            app.BoneAddButton.Text = 'Add';
            app.BoneListListBoxLabel = uilabel(app.BonesPanel);
            app.BoneListListBoxLabel.Text = 'Bone List';
            app.BoneListListBox = uilistbox(app.BonesPanel);
            app.BoneListListBox.Items = {'Bone File Name'};
            app.BoneListListBox.Value = {};
            app.CurrentDirectoryLabel = uilabel(app.BonesPanel);
            app.CurrentDirectoryLabel.Text = 'Current Directory';
            app.BonesDirectoryField = uieditfield(app.BonesPanel, 'text');
            app.BonesDirectoryField.Editable = 'off';
            app.TrackingFilesPanel = uipanel(app.LeftPanel);
            app.TrackingFilesPanel.Title = 'Tracking Files';
            app.TrackUpButton = uibutton(app.TrackingFilesPanel, 'state');
            app.TrackUpButton.ValueChangedFcn = createCallbackFcn(app, @TrackUpButtonValueChanged, true);
            app.TrackUpButton.Text = 'Up';
            app.TrackDownButton = uibutton(app.TrackingFilesPanel, 'state');
            app.TrackDownButton.ValueChangedFcn = createCallbackFcn(app, @TrackDownButtonValueChanged, true);
            app.TrackDownButton.Text = 'Down';
            app.TrackDeleteButton = uibutton(app.TrackingFilesPanel, 'state');
            app.TrackDeleteButton.ValueChangedFcn = createCallbackFcn(app, @TrackDeleteButtonValueChanged, true);
            app.TrackDeleteButton.Text = 'Delete';
            app.TrackAddButton = uibutton(app.TrackingFilesPanel, 'state');
            app.TrackAddButton.ValueChangedFcn = createCallbackFcn(app, @TrackAddButtonValueChanged, true);
            app.TrackAddButton.Text = 'Add';
            app.TrackingListListBoxLabel = uilabel(app.TrackingFilesPanel);
            app.TrackingListListBoxLabel.Text = 'Tracking List';
            app.TrackingListListBox = uilistbox(app.TrackingFilesPanel);
            app.TrackingListListBox.Items = {'Tracking File Name'};
            app.TrackingListListBox.Value = {};
            app.CurrentDirectoryLabel_2 = uilabel(app.TrackingFilesPanel);
            app.CurrentDirectoryLabel_2.Text = 'Current Directory';
            app.TrackingDirectoryField = uieditfield(app.TrackingFilesPanel, 'text');
            app.TrackingDirectoryField.Editable = 'off';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.MocapFileButton = uibutton(app.LeftPanel, 'push');
            app.MocapFileButton.ButtonPushedFcn = createCallbackFcn(app, @MocapFileButtonPushed, true);
            app.MocapFileButton.Text = '/';
            app.MocapFileC3DEditFieldLabel = uilabel(app.LeftPanel);
            app.MocapFileC3DEditFieldLabel.Text = 'Mocap File (C3D)';
            app.MocapFileC3DEditField = uieditfield(app.LeftPanel, 'text');
            app.MocapFileC3DEditField.Editable = 'off';
            app.CoregFileButton = uibutton(app.LeftPanel, 'push');
            app.CoregFileButton.ButtonPushedFcn = createCallbackFcn(app, @CoregFileButtonPushed, true);
            app.CoregFileButton.Text = '/';
            app.CoregistrationfileTXTEditFieldLabel = uilabel(app.LeftPanel);
            app.CoregistrationfileTXTEditFieldLabel.Text = 'Co-registration file (TXT)';
            app.CoregistrationfileTXTEditField = uieditfield(app.LeftPanel, 'text');
            app.CoregistrationfileTXTEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.FrameLabel = uilabel(app.RightPanel);
            app.FrameLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.RightPanel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.PlayButton = uibutton(app.RightPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ClearMenu = uimenu(app.Menu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.CleartrialdataonlyMenu = uimenu(app.Menu);
            app.CleartrialdataonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @CleartrialdataonlyMenuSelected, true);
            app.CleartrialdataonlyMenu.Text = 'Clear trial data only';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xromm_visualisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end